classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TidsfnsterEditField       matlab.ui.control.NumericEditField
        TidsfnsterEditFieldLabel  matlab.ui.control.Label
        VljfilButton              matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ecg_data % Description
        timestamps
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VljfilButton
        function VljfilButtonPushed(app, event)
            [file, path] = uigetfile("*.mat")
            load(file, "Shimmer_9DF2_ECG_LL_LA_24BIT_CAL", "Shimmer_9DF2_TimestampSync_Unix_CAL")
            app.ecg_data = Shimmer_9DF2_ECG_LL_LA_24BIT_CAL;
            app.timestamps = Shimmer_9DF2_TimestampSync_Unix_CAL;
            %plot(app.UIAxes, app.timestamps)
            %disp(Shimmer_9DF2_ECG_LA_RA_24BIT_CAL(1:10))
        end

        % Value changed function: TidsfnsterEditField
        function TidsfnsterEditFieldValueChanged(app, event)
            value = app.TidsfnsterEditField.Value;
            ecg_data = app.ecg_data;
            ecg_data = ecg_data-movmean(ecg_data,1000);
            [pulse, indices] = momentan_puls(ecg_data, app.timestamps, 0.6, 12);
            plot(app.UIAxes, indices/512, pulse, "r");
            app.UIAxes.xlim([0,600]);
            grid on
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Puls över tid')
            xlabel(app.UIAxes, 't/s')
            ylabel(app.UIAxes, 'puls')
            zlabel(app.UIAxes, 'Z')
            app.VljfilButton = uibutton(app.UIFigure, 'push');
            app.VljfilButton.ButtonPushedFcn = createCallbackFcn(app, @VljfilButtonPushed, true);
            app.VljfilButton.Text = 'Välj fil';
            app.TidsfnsterEditFieldLabel = uilabel(app.UIFigure);
            app.TidsfnsterEditFieldLabel.Text = 'Tidsfönster';
            app.TidsfnsterEditField = uieditfield(app.UIFigure, 'numeric');
            app.TidsfnsterEditField.ValueChangedFcn = createCallbackFcn(app, @TidsfnsterEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end